classdef object_segmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        PanelLayout                     matlab.ui.container.GridLayout
        InputNum2EditFieldLabel         matlab.ui.control.Label
        InputNum1EditFieldLabel         matlab.ui.control.Label
        InputNum1EditField              matlab.ui.control.NumericEditField
        InputNum2EditField              matlab.ui.control.NumericEditField
        SystemLogTextArea               matlab.ui.control.TextArea
        SystemLogTextAreaLabel          matlab.ui.control.Label
        ExecuteButton                   matlab.ui.control.Button
        ObjectDetectionDropDown         matlab.ui.control.DropDown
        ObjectDetectionDropDownLabel    matlab.ui.control.Label
        EdgeDetectionAlgorithmDropDown  matlab.ui.control.DropDown
        EdgeDetectionAlgorithmDropDownLabel  matlab.ui.control.Label
        SelectedImageLabel              matlab.ui.control.Label
        BrowseImageLabel                matlab.ui.control.Label
        BrowseImageButton               matlab.ui.control.Button
        TitleLabel                      matlab.ui.control.Label
        AlgoTitleLabel                  matlab.ui.control.Label
        ImageGrid                       matlab.ui.container.GridLayout
        ObjectDetectionGrid             matlab.ui.container.GridLayout
        ObjectDetectionImage            matlab.ui.control.Image
        ObjectDetectionLabel            matlab.ui.control.Label
        EdgeDetectionGrid               matlab.ui.container.GridLayout
        EdgeDetectionImage              matlab.ui.control.Image
        EdgeDetectionLabel              matlab.ui.control.Label
        InputImageGrid                  matlab.ui.container.GridLayout
        InputImage                      matlab.ui.control.Image
        InputImageLabel                 matlab.ui.control.Label
    end

    properties (Access = public)
        INPUT_IMAGE = [];
        EDGE_DETECTED_IMAGE = [];
        OBJECT_DETECTED_IMAGE = [];
        LOG_STR = "";
    end
    
    properties (Access = private)

    end


    methods (Access = public)
        function display_image_on_ui(~, img, ui)
            if (size(img, 3) == 1)
                grayImage = img;
                image_to_display = cat(3, grayImage, grayImage, grayImage);
            else
                image_to_display = img;
            end
            ui.ImageSource = image_to_display;
        end

        function set_panel_config(app, config)
            if (ismember(1, config))
                app.InputNum1EditField.Visible = "on";
                app.InputNum1EditFieldLabel.Visible = "on";
            else
                app.InputNum1EditField.Visible = "off";
                app.InputNum1EditFieldLabel.Visible = "off";
            end

            if (ismember(2, config))
                app.InputNum2EditField.Visible = "on";
                app.InputNum2EditFieldLabel.Visible = "on";
            else
                app.InputNum2EditField.Visible = "off";
                app.InputNum2EditFieldLabel.Visible = "off";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            % Open file dialog to browse for an image file
            [fileName, filePath] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            
            % If the user selected a file, display it in the UIAxes
            if ischar(fileName)
                % Full file path of the selected image
                fullFileName = fullfile(filePath, fileName);

                % Change label
                app.SelectedImageLabel.Text = fileName;
                app.INPUT_IMAGE= imread(fullFileName);
                
                display_image_on_ui(app, app.INPUT_IMAGE, app.InputImage);
            end
        end

        % Value changed function: EdgeDetectionAlgorithmDropDown
        function EdgeDetectionAlgorithmDropDownValueChanged(app, event)
            value = app.EdgeDetectionAlgorithmDropDown.Value;
            app.AlgoTitleLabel.Text = value;
                
            if (value == "Laplace")
                app.set_panel_config([1]);
                app.InputNum1EditFieldLabel.Text = "NxN Kernel";
                app.InputNum1EditField.Value = 3;
            elseif (value == "LoG")
                app.set_panel_config([1, 2]);
                app.InputNum1EditFieldLabel.Text = "NxN Kernel"; 
                app.InputNum2EditFieldLabel.Text = "Sigma";
                app.InputNum1EditField.Value = 3;
                app.InputNum2EditField.Value = 1;
            elseif (value == "Sobel")
                app.set_panel_config([1]);
                app.InputNum1EditFieldLabel.Text = "Multiplier Scale";
                app.InputNum1EditField.Value = 1;
            elseif(value == "Prewitt")
                app.set_panel_config([1]);
                app.InputNum1EditFieldLabel.Text = "Multiplier Scale";
                app.InputNum1EditField.Value = 1;
            elseif (value == "Roberts")
                % TODO 
            else
                % TODO 
            end
            
        end

        % Value changed function: ObjectDetectionDropDown
        function ObjectDetectionDropDownValueChanged(app, event)
            value = app.ObjectDetectionDropDown.Value;
            if value == "Line"
                app.TitleLabel.Text = "Line Detection";
                app.ImageGrid.ColumnWidth = {'1x', '1x', '1x'};
                app.ObjectDetectionImage.Visible = "on";
                app.ObjectDetectionLabel.Visible = "on";
                app.ObjectDetectionLabel.Text = "Line Detection";
            elseif value == "Circle"
                app.TitleLabel.Text = "Circle Detection";
                app.ImageGrid.ColumnWidth = {'1x', '1x', '1x'};
                app.ObjectDetectionImage.Visible = "on";
                app.ObjectDetectionLabel.Visible = "on";
                app.ObjectDetectionLabel.Text = "Circle Detection";
            else % value == "None"
                app.TitleLabel.Text = "Edge Detection";
                app.ImageGrid.ColumnWidth = {'1x', '1x', 0};
                app.ObjectDetectionImage.Visible = "off";
                app.ObjectDetectionLabel.Visible = "off";
            end

            
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            addpath("src\edge_detection\");
            addpath("src\matrix\");
            algo = app.EdgeDetectionAlgorithmDropDown.Value;
   

            if (algo == "Laplace")
                app.EDGE_DETECTED_IMAGE = laplacian_edge_detection(app.INPUT_IMAGE, app.InputNum1EditField.Value);
            elseif(algo == "LoG")
                app.EDGE_DETECTED_IMAGE = laplacian_of_gaussian_edge_detection(app.INPUT_IMAGE, app.InputNum1EditField.Value, app.InputNum2EditField.Value);
            elseif(algo == "Sobel")
                app.EDGE_DETECTED_IMAGE = sobel_edge_detection(app.INPUT_IMAGE, app.InputNum1EditField.Value);
            elseif(algo == "Prewitt")
                app.EDGE_DETECTED_IMAGE = prewitt_edge_detection(app.INPUT_IMAGE, app.InputNum1EditField.Value);
            elseif(algo == "Roberts")
                app.EDGE_DETECTED_IMAGE = roberts_edge_detection(app.INPUT_IMAGE);
            else % value == "Canny
                app.EDGE_DETECTED_IMAGE = canny_edge_detection(app.INPUT_IMAGE);
            end
            display_image_on_ui(app, app.EDGE_DETECTED_IMAGE, app.EdgeDetectionImage);


            if (app.ObjectDetectionDropDown.Value == "Line")
                % TODO
            elseif (app.ObjectDetectionDropDown.Value == "Circle")
                % TODO
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageGrid = uigridlayout(app.GridLayout);
            app.InputImageGrid = uigridlayout(app.ImageGrid);
            app.InputImageLabel = uilabel(app.InputImageGrid);
            app.InputImageLabel.Text = 'Input Image';
            app.InputImage = uiimage(app.InputImageGrid);
            app.EdgeDetectionGrid = uigridlayout(app.ImageGrid);
            app.EdgeDetectionLabel = uilabel(app.EdgeDetectionGrid);
            app.EdgeDetectionLabel.Text = 'Edge Detected Image';
            app.EdgeDetectionImage = uiimage(app.EdgeDetectionGrid);
            app.ObjectDetectionGrid = uigridlayout(app.ImageGrid);
            app.ObjectDetectionLabel = uilabel(app.ObjectDetectionGrid);
            app.ObjectDetectionLabel.Visible = 'off';
            app.ObjectDetectionLabel.Text = 'Line Detected Image';
            app.ObjectDetectionImage = uiimage(app.ObjectDetectionGrid);
            app.ObjectDetectionImage.Visible = 'off';
            app.PanelLayout = uigridlayout(app.GridLayout);
            app.AlgoTitleLabel = uilabel(app.PanelLayout);
            app.AlgoTitleLabel.Text = 'Laplace';
            app.TitleLabel = uilabel(app.PanelLayout);
            app.TitleLabel.Text = 'Edge Detection';
            app.BrowseImageButton = uibutton(app.PanelLayout, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse...';
            app.BrowseImageLabel = uilabel(app.PanelLayout);
            app.BrowseImageLabel.Text = 'Input Image';
            app.SelectedImageLabel = uilabel(app.PanelLayout);
            app.SelectedImageLabel.Text = 'No image has been chosen';
            app.EdgeDetectionAlgorithmDropDownLabel = uilabel(app.PanelLayout);
            app.EdgeDetectionAlgorithmDropDownLabel.Text = 'Edge Detection Algorithm';
            app.EdgeDetectionAlgorithmDropDown = uidropdown(app.PanelLayout);
            app.EdgeDetectionAlgorithmDropDown.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.EdgeDetectionAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgeDetectionAlgorithmDropDownValueChanged, true);
            app.EdgeDetectionAlgorithmDropDown.Value = 'Laplace';
            app.ObjectDetectionDropDownLabel = uilabel(app.PanelLayout);
            app.ObjectDetectionDropDownLabel.Text = 'Object Detection';
            app.ObjectDetectionDropDown = uidropdown(app.PanelLayout);
            app.ObjectDetectionDropDown.Items = {'None', 'Line', 'Circle'};
            app.ObjectDetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectDetectionDropDownValueChanged, true);
            app.ObjectDetectionDropDown.Value = 'None';
            app.ExecuteButton = uibutton(app.PanelLayout, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.SystemLogTextAreaLabel = uilabel(app.PanelLayout);
            app.SystemLogTextAreaLabel.Text = 'System Log';
            app.SystemLogTextArea = uitextarea(app.PanelLayout);
            app.InputNum2EditField = uieditfield(app.PanelLayout, 'numeric');
            app.InputNum2EditField.Visible = 'off';
            app.InputNum2EditField.Value = 1;
            app.InputNum1EditField = uieditfield(app.PanelLayout, 'numeric');
            app.InputNum1EditField.Value = 3;
            app.InputNum1EditFieldLabel = uilabel(app.PanelLayout);
            app.InputNum1EditFieldLabel.Text = 'NxN Kernel';
            app.InputNum2EditFieldLabel = uilabel(app.PanelLayout);
            app.InputNum2EditFieldLabel.Visible = 'off';
            app.InputNum2EditFieldLabel.Text = 'InputNum2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = object_segmentation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end